classdef codeauto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Button2     matlab.ui.control.Button
        Button      matlab.ui.control.StateButton
        TextArea_3  matlab.ui.control.TextArea
        Label_3     matlab.ui.control.Label
        TextArea_2  matlab.ui.control.TextArea
        Label_2     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Button, TextArea_2
        function ButtonValueChanged(app, event)
            value = app.Button.Value;

        end

        % Value changing function: TextArea_2
        function TextArea_2ValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changed function: TextArea_3
        function TextArea_3ValueChanged(app, event)
            value = app.TextArea_3.Value;
            
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            start_num =str2double(app.TextArea_2.Value);
            end_num = str2double(app.TextArea_3.Value);
            file_path = 'D:\ANALYZE\jisaku3.m';
            % ファイル内の変数を読み込み
            load(file_path);  % ファイル内の変数を読み込む
            % ファイルを実行
            for num = start_num:end_num
                % ファイル実行のためのコードを記述
                runFileWithVariables(file_path, num, start_num, end_num);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '開始';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.TextArea_2.ValueChangingFcn = createCallbackFcn(app, @TextArea_2ValueChanging, true);
            app.TextArea_2.Value = {'4000'};
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '終了';
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_3.ValueChangedFcn = createCallbackFcn(app, @TextArea_3ValueChanged, true);
            app.TextArea_3.Value = {'27000'};
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = 'Button';
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Text = 'Button2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = codeauto

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end